classdef DockerPref < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        ISETDockerPrefsEditorLabel  matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        ISETDockerPrefPanel         matlab.ui.container.Panel
        PrefsPanelGrid              matlab.ui.container.GridLayout
        Button                      matlab.ui.control.Button
        LoadPrefButton              matlab.ui.control.Button
        CreatePresetButton          matlab.ui.control.Button
        EditFieldsButton            matlab.ui.control.StateButton
        WritePrefButton             matlab.ui.control.Button
        PfFieldsPanel               matlab.ui.container.Panel
        PfFieldsPanelGrid           matlab.ui.container.GridLayout
        PresetsPanel                matlab.ui.container.Panel
        PresetPanelGrid             matlab.ui.container.GridLayout
        LoadPresetsButton           matlab.ui.control.Button
        DeletePresetButton          matlab.ui.control.Button
        WritePresetsButton          matlab.ui.control.Button
        GeneratePrefButton          matlab.ui.control.Button
        PresetDropDown              matlab.ui.control.DropDown
        PresetDropDownLabel         matlab.ui.control.Label
        PsFieldsPanel               matlab.ui.container.Panel
        PsFieldsPanelGrid           matlab.ui.container.GridLayout
    end

    
    properties (Constant, Access = private)
        DockerPresetTemplate =  struct( ...
            'label', '', ...
            'renderContext', '', ...
            'remoteHost', '', ...
            'device', '', ...
            'deviceID', '', ...
            'dockerImage', '', ...
            'remoteUser', '', ...
            'workDir', '', ...
            'PBRTResources', '' ...
        );
    end

    properties (Access = private)
        % The data structures that the app uses
       
        Username                    % local username
        sitePresets                 % site-wide presets file
        myPresets                   % personalized presets file
        dockerPresets = struct([])  % an array of presets 
        activePresetsTable          % manipulate indices instead of array
        curIdx                      % index into activePresetsTable
        
        PresetGridHandle = struct();% the grid
        PresetFields = struct();    % the EditFields
        
        curPrefs = struct();        % the current ISETDocker prefs 
        curPrefsChanged = false;
        PrefGridHandle = struct();
        PrefFields = struct();
        
        ignoredprefs = struct();

        aliasFields = struct( ...
            'deviceId',         'deviceID', ...
            'deviceID',         'deviceId' ...
        );
        ignoreFields = {
            'PBRTContainer', ...
            'batch', ...
            'remotePBRTResources'
        };

    
    end
    
    methods (Access = private)
        
        function [grid,handles] = buildFieldsPanel(app, parentPanel, baseStruct, pTag)

            % Get the empty struct
            fieldNames = fieldnames(baseStruct);
            numFields = numel(fieldNames);

            % Create a grid layout inside PrefsPanel
            grid = uigridlayout(parentPanel, [numFields, 2]);
            grid.RowSpacing = 5;
            grid.ColumnSpacing = 10;
            grid.RowHeight = repmat({30}, 1, numFields);
            grid.ColumnWidth = {'1x', '2x'};


            % Store component handles in a struct for easy reference
            %  app.([pTag 'Fields']) = struct();
            for i = 1:numFields
                fieldName = fieldNames{i};
                % for now just use the field name. Later, maybe put in spaces
                % labelText = insertSpaces(fieldName);
                % or
                % labelText = fieldtolabel(fieldName, pTag);
                %
                labelText = fieldName;


                % Create Label
                uilabel(grid, ...
                    'Text', labelText, ...
                    'HorizontalAlignment', ['left' ...
                    '']);

                % Create Edit Field
                handles.(fieldName) = uieditfield(grid, ...
                   'Tag', ['cur' pTag '.' fieldName], ...
                   'Editable', 'off' ...
                );

                % Later, we may create panel specific callbacks
                % for now let the work happen in the callback function

                callbackName = 'EditFieldValueChanged';
                handles.(fieldName).ValueChangedFcn = @(src, event)app.(callbackName)(src,event);
            end
        end
        
        function populateEditFields(app, uiHandles, dataStruct)
            fields = fieldnames(dataStruct);
            for i = 1:numel(fields)
                f = fields{i};
                if isfield(uiHandles, f)
                    %Do we check for numbers here?  or should we do it
                    %earlier?
                    uiHandles.(f).Value = dataStruct.(f);
                end
            end
        end
        
        %% Read the presets from the docker/config directory.
        function  getDockerPresets(app)
           
            %
            % what should we have as static defaults?  A local cpu version?
            % See if there's a json file that we can read
            % once we've dealt with the json file we can see if there is
            % an ISETDocker pref that wants to be made into a preset
            % if yes, read it in and build the menu
            % %otherwise
            %
            
            % get USER and other useful stuff 
            % where do we want to stash it?
            %
        
            if isfile(app.myPresets)
                presetsFile = app.myPresets;
            elseif isfile(app.sitePresets)
                fprintf('You don''t yet have a personal presets file (%s).\nUsing the default presets.\n', app.myPresets);
                presetsFile = app.sitePresets;
            else
                % change this to a warning and generate some generic
                % presets
                error('Site presets file %s not found either', app.sitePresets);
            end   
            if (presetsFile)
                try
                    rawtext = fileread(presetsFile);
                    decodedData = jsondecode(rawtext);

                    if ~isstruct(decodedData) || isempty(decodedData)
                        warning('JSON file %s is empty or has invalid structure.', presetsFile);
                        return;
                    end
                catch err
                    warning(err.identifier, 'Error reading JSON file %s: %s', presetsFile, err.message);
                    return;
                end
            end
            try
                newpresets = app.DockerPresetTemplate([]);
                newpresets = [newpresets ; decodedData];    
            catch concaterr
                warning(concaterr.identifier, 'Structure mismatch in JSON file: %s', concaterr.message);
                newpresets = salvagePresetsFile(app, decodedData);
            end
            % We will be using the label as a handle.  Make sure they're
            % unique
            labels = {newpresets.label};
            [uniquelabels, ~, labelIndices] = unique(labels,'stable');
            labelCounts = accumarray(labelIndices(:), 1);
            duplicateMask = labelCounts > 1;
            if any(duplicateMask)
                error("Duplicate Entries in %s", presetsFile);
            end

            extrapresets = repmat(app.DockerPresetTemplate, 4, 1);
            app.dockerPresets = [newpresets ; extrapresets];
            app.activePresetsTable = 1:numel(newpresets);
            app.curIdx = 1;
        end
        
        %%  Read the Matlab ISETDocker prefs slots        
        function newpref = getDockerPrefs(app)
            % check to see if the pref exists
            newpref = app.DockerPresetTemplate;
            if ~ispref("ISETDocker")
                %if not return null prefs
                return
            end
            % get the ISETDocker pref
            prefs = getpref("ISETDocker");

            % cycle through the pref fields
            fields = fieldnames(prefs);
            for i = 1:numel(fields)
                prefName = fields{i};
                name = prefName;
                value = prefs.(name);
               
                % First make sure it's a valid field name
                if ismember(name, app.ignoreFields)
                    % this is where we deal with 'batch', 'PBRTContainer', etc.
                    % put it on a special list and go to the next
                        app.ignoredprefs.(name) = value;
                        continue;
                end

                %{
                % Should we allow the isetdocker struct and the ISETDocker prefs to be different
                % or force them to stay in sync?

                % is it aliased?
                if isfield(app.aliasFields, name)
                    aliased = app.aliasFields.(name);
                    % Is the new name a field
                    if isfield(newpref,aliased)
                        name = aliased;
                    else
                        error( ...
                            "bad entry in %s: %s aliased to %s, not a field in %s", ...
                            "app.aliasFields", name, aliased, "dockerPreset" ...
                        );
                    end
                elseif ~isfield(newpref, name)
                %}
                if ~isfield(newpref, name)
                    % not ignored, not aliased, not a known field
                    error("Bad entry in ISETDocker pref: %s is not a known field", ...
                            name);
                end
                % name is good, let's clean up value

                % we want value to be a char array or string
                if ~ischar(value) && ~isstring(value)
                    if isnumeric(value) && isscalar(value)
                        % if it's a simple scalar number, we make it into a string
                        value = num2str(value);
                    else
                        %Probably not ok
                        error("Bad entry in ISETDocker pref: %s.(%s) should be a char or string", name, value)
                    end
                end
                newpref.(name) = value;
            end

            % where should we keep the ones we recognize, but don't support
            % here.  Like the PBRTcontainer, which if we're changing
            % anything  in the prefs, we should probably clear.
            
            app.curPrefs = newpref;
            
        end

        %% Write out the current prefs as a new entry in the docker/config
        function newpref = saveDockerPrefs(app)
            % We should probably enable deleting from this file, as
            % well.
            disp('NYI');
        end
        
        %% NYI
        function newpresets = salvagePresetsFile(app, decodedData)
            warning("Salvage not yet implemented");
            return;
        end
        
        %% Find the preset from the label
        function idx = findpreset(app, label)
            %look for label in active presets
            activePresets = app.dockerPresets(app.activePresetsTable);
            idx = find(strcmp({activePresets.label}, label),1);
         
            if isempty(idx) % didn't find
                idx = 0;
            elseif isscalar(idx) % found 1
                return;
            else  % we shouldn't have more than one
                error("Duplicate labels in activePresets: %s", label);
            end
        end
        
        %% Generic ValueChanged callback for Edit Fields
        function EditFieldValueChanged(app, src, event)
            % divvy src.Tag to get the correct field and set the new value
            [panel, remainder] = strtok(src.Tag, '.');
            field = remainder(2:end);
            % hook for more individual handling
            switch field
                otherwise
                    app.(panel).(field) = src.Value;
                    app.([panel 'Changed']) = true;
            end
        end
  
        %% find an empty slot, allocate more if needed
        function index = getEmptyPresetSlot(app)
            unusedSlots = setdiff(1:numel(app.dockerPresets), app.activePresetsTable);
            if ~isempty(unusedSlots)
                index = unusedSlots(1);
            else
                app.dockerPresets(end+1:end+4) = repmat(app.DockerPresetTemplate, 4, 1);
                index = numel(app.dockerPresets) - 4; % first new slot
            end  
        end
        
        %% update the dropdown menu after changing the activePresetsTable
        function updatePresetDropDown(app)
            %
            idx = app.curIdx;
            activePresets = app.dockerPresets(app.activePresetsTable);
            labels = {activePresets.label};
            app.PresetDropDown.Items = labels;
            app.PresetDropDown.Value = labels(idx);
            % do I have to set Index as well?
            populateEditFields(app, app.PresetFields, activePresets(idx));            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            % fun things to know about
            %
            app.Username = getenv("USER");

            %
            % create a Presets array and fill it in
            %
            app.dockerPresets = app.DockerPresetTemplate([]);
            app.sitePresets = fullfile(piRootPath, 'docker/config','dockerpresets.json');
            app.myPresets = fullfile(piRootPath, 'local/config','dockerpresets.json');           
            %
            % get the list of presets
            % when we return, we should have gotten presets from one of the
            % files or made some up or thrown an error
            %
            getDockerPresets(app);

            % Update the dropdown menu from the preset array
            updatePresetDropDown(app);

            % create the Presets panel and populate its fields
            curPreset = app.dockerPresets(app.activePresetsTable(app.curIdx));
            [app.PresetGridHandle, app.PresetFields] = buildFieldsPanel( ...
                app, app.PsFieldsPanel, curPreset, 'Preset');
            populateEditFields(app, app.PresetFields, curPreset);
     
            %
            % Now the current Prefs
            %
            getDockerPrefs(app);

            app.ISETDockerPrefPanel.Title = "Current ISETDocker Pref";
            %
            % create the Prefs panel and populate its fields
            %   
            [app.PrefGridHandle, app.PrefFields] = buildFieldsPanel( ...
                app, app.PfFieldsPanel, app.curPrefs, 'Prefs');
            populateEditFields(app, app.PrefFields, app.curPrefs);


%{
            % This should be in buildFields
            % generic ValueChanged Function
            %
            app.pfDockerContextEF.Tag = 'pfDockerContextEF';
            app.pfDockerContextEF.ValueChangedFcn = ...
                @(src,event) app.pfGenericEFValueChanged(src,event);            
%}


        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            % When we choose a pull down, we reader the selection and
            % fill the other fields.
            idx = event.ValueIndex;
            label = event.Value;
            app.curIdx = idx;
        
            populateEditFields(app, app.PresetFields, app.dockerPresets(app.activePresetsTable(idx)));

    %           thisD = isetdocker;

           
            % might change where we get things...
            % this is all ease-of-use stuff to generate some presets.  
            % When we're doing stuff we just
            % use getpref for the current setup and just go.  We can figure
            % out wh
            %

        end

        % Button pushed function: GeneratePrefButton
        function GeneratePrefButtonPushed(app, event)
            % get the current preset
            % write it to the current pref
            % Let's try the simple way first
            curPreset = app.dockerPresets(app.curIdx);
            app.curPrefs = curPreset;
            % update the current pref panel
            populateEditFields(app, app.PrefFields, app.curPrefs);
            newTitle = sprintf("New Pref from '%s'",curPreset.label);
            app.ISETDockerPrefPanel.Title = newTitle;
            app.curPrefsChanged = true;
        end

        % Button pushed function: WritePrefButton
        function WritePrefButtonPushed(app, event)
            % write out the new prefs

            %clear the old pref structure
            rmpref('ISETDocker');

            app.curPrefsChanged = false;
            % go through app.curPref and set each field in ISETDocker           
            fields = fieldnames(app.curPrefs);
            for i = 1:numel(fields)
                setpref('ISETDocker', fields{i}, app.curPrefs.(fields{i}));
            end
            app.curPrefsChanged = false;
%{
%   just make the isgnored fields disappear for now
            extras = fieldnames(app.ignoredprefs);
            for i = 1:numel(extras)
                setpref('ISETDocker', extras{i}, app.ignoredprefs.(extras{i}))
            end
%}
        end

        % Value changed function: EditFieldsButton
        function EditFieldsButtonValueChanged(app, event)
            % the value has been changed by the time this function is called
            editable = app.EditFieldsButton.Value;

            % now put the individual edit fields into the same state
            fields = fieldnames(app.PrefFields);
            for i =1:numel(fields)
                app.PrefFields.(fields{i}).Editable = editable; 
                
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            % maybe check to see it anything should be saved?
            app.delete ;
        end

        % Button pushed function: LoadPrefButton
        function LoadPrefButtonPushed(app, event)
            % call app.getDockerPrefs and populate the fields
            % we don't need to redo the edit fields
            app.getDockerPrefs();
            populateEditFields(app, app.PrefFields, app.curPrefs);  
            app.ISETDockerPrefPanel.Title = "Current ISETDocker Pref";

        end

        % Button pushed function: CreatePresetButton
        function CreatePresetButtonPushed(app, event)
            % add the values in curPref as a preset
            %
            % check for label duplicationl
            label = app.curPrefs.label
            if isempty(label) || all(isspace(label))
                % dialog box: tell them blank's not okay
                uialert(app.UIFigure, 'Add a label to the Pref field to create a preset.', 'No Preset Label');
                return;
            end

            idx = findpreset(app, label);
            if idx
                queststring = sprintf('%s exists in Presets. Overwrite existing preset', label);
                answer = questdlg(queststring, 'Overwrite existing Preset?', 'No');
                switch answer
                    case 'Yes'
                         %this space left blank
                    otherwise
                        uialert(app.UIFigure, ...
                            'Change the label in the Pref panel so that it is unique!','Label Already In Use');
                        return;
                end
            end
            
       
            if ~idx
                idx = getEmptyPresetSlot(app);
            end
            % we now have a an index for a preset slot to write into
            % write into the slot and move it to the top
            app.dockerPresets(idx) = app.curPrefs;
            app.activePresetsTable = [idx app.activePresetsTable];
            app.curIdx = 1;
            %
            % update the menu
            updatePresetDropDown(app);
        end

        % Button pushed function: WritePresetsButton
        function WritePresetsButtonPushed(app, event)
            % Write out these presets as a JSON file
            jsonFile = app.myPresets;

            activePresets = app.dockerPresets(app.activePresetsTable);
            jsonStr = jsonencode(activePresets, "PrettyPrint",true);
            jsonStr = [ jsonStr newline ];
            fid = fopen(jsonFile, "w"); % open presets file 
            if fid == -1
                error('Could not open %s for writing', jsonFile);
            end
            fwrite(fid, jsonStr, 'char');
            fclose(fid);          
        end

        % Button pushed function: DeletePresetButton
        function DeletePresetButtonPushed(app, event)
            % remove curIdx from activePresetsTable,
            %           
            idx = app.curIdx;
            app.activePresetsTable(idx) = [];

            % update the menu
            app.curIdx = 1;
            updatePresetDropDown(app);
        end

        % Button pushed function: LoadPresetsButton
        function LoadPresetsButtonPushed(app, event)
            app.getDockerPresets();
            % Update the dropdown menu from the preset array
            updatePresetDropDown(app);

            % create the Presets panel and populate its fields
            curPreset = app.dockerPresets(app.activePresetsTable(app.curIdx));
            populateEditFields(app, app.PresetFields, curPreset);
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PresetsPanel = uipanel(app.GridLayout2);
            app.PresetsPanel.Title = 'Presets';
            app.PresetPanelGrid = uigridlayout(app.PresetsPanel);
            app.PsFieldsPanel = uipanel(app.PresetPanelGrid);
            app.PsFieldsPanelGrid = uigridlayout(app.PsFieldsPanel);
            app.PresetDropDownLabel = uilabel(app.PresetPanelGrid);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.PresetPanelGrid);
            app.PresetDropDown.Items = {'Local CPU', '<New Preset>'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.PresetDropDown.Placeholder = '<Choose a Preset>';
            app.PresetDropDown.Value = 'Local CPU';
            app.GeneratePrefButton = uibutton(app.PresetPanelGrid, 'push');
            app.GeneratePrefButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePrefButtonPushed, true);
            app.GeneratePrefButton.Text = 'Generate Pref';
            app.WritePresetsButton = uibutton(app.PresetPanelGrid, 'push');
            app.WritePresetsButton.ButtonPushedFcn = createCallbackFcn(app, @WritePresetsButtonPushed, true);
            app.WritePresetsButton.Text = 'Write Presets';
            app.DeletePresetButton = uibutton(app.PresetPanelGrid, 'push');
            app.DeletePresetButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePresetButtonPushed, true);
            app.DeletePresetButton.Text = 'Delete Preset';
            app.LoadPresetsButton = uibutton(app.PresetPanelGrid, 'push');
            app.LoadPresetsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetsButtonPushed, true);
            app.LoadPresetsButton.Text = 'Load Presets';
            app.ISETDockerPrefPanel = uipanel(app.GridLayout2);
            app.ISETDockerPrefPanel.Title = 'ISETDocker Pref';
            app.PrefsPanelGrid = uigridlayout(app.ISETDockerPrefPanel);
            app.PfFieldsPanel = uipanel(app.PrefsPanelGrid);
            app.PfFieldsPanelGrid = uigridlayout(app.PfFieldsPanel);
            app.WritePrefButton = uibutton(app.PrefsPanelGrid, 'push');
            app.WritePrefButton.ButtonPushedFcn = createCallbackFcn(app, @WritePrefButtonPushed, true);
            app.WritePrefButton.Tooltip = {'use setpref() to create a new version of the ISETDocker pref'};
            app.WritePrefButton.Text = 'Write Pref';
            app.EditFieldsButton = uibutton(app.PrefsPanelGrid, 'state');
            app.EditFieldsButton.ValueChangedFcn = createCallbackFcn(app, @EditFieldsButtonValueChanged, true);
            app.EditFieldsButton.Tooltip = {'Make the pref fields editable'};
            app.EditFieldsButton.Text = 'Edit Fields';
            app.CreatePresetButton = uibutton(app.PrefsPanelGrid, 'push');
            app.CreatePresetButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePresetButtonPushed, true);
            app.CreatePresetButton.Text = 'Create Preset';
            app.LoadPrefButton = uibutton(app.PrefsPanelGrid, 'push');
            app.LoadPrefButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPrefButtonPushed, true);
            app.LoadPrefButton.Text = 'Load Pref';
            app.Button = uibutton(app.PrefsPanelGrid, 'push');
            app.ISETDockerPrefsEditorLabel = uilabel(app.GridLayout);
            app.ISETDockerPrefsEditorLabel.Text = 'ISETDocker Prefs Editor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DockerPref

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end