classdef remoteDocker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        FileMenu_Close                matlab.ui.container.Menu
        DeviceIDEditField             matlab.ui.control.NumericEditField
        DeviceIDEditField_2Label      matlab.ui.control.Label
        BatchEditField                matlab.ui.control.NumericEditField
        BatchEditField_2Label         matlab.ui.control.Label
        PBRTContainerEditField        matlab.ui.control.EditField
        PBRTContainerLabel            matlab.ui.control.Label
        PBRTResourcesEditField        matlab.ui.control.EditField
        PBRTResourcesLabel            matlab.ui.control.Label
        remotePBRTResourcesEditField  matlab.ui.control.EditField
        remotePBRTResourcesLabel      matlab.ui.control.Label
        DeviceEditField               matlab.ui.control.EditField
        DeviceEditFieldLabel          matlab.ui.control.Label
        RendercontextEditField        matlab.ui.control.EditField
        RendercontextEditFieldLabel   matlab.ui.control.Label
        RemotehostEditField           matlab.ui.control.EditField
        RemotehostEditFieldLabel      matlab.ui.control.Label
        RemoteWorkDirEditField        matlab.ui.control.EditField
        RemoteWorkDirEditFieldLabel   matlab.ui.control.Label
        RemoteuserEditField           matlab.ui.control.EditField
        RemoteuserEditFieldLabel      matlab.ui.control.Label
        DockerimageEditField          matlab.ui.control.EditField
        DockerimageEditFieldLabel     matlab.ui.control.Label
        ChooserenderDropDown          matlab.ui.control.DropDown
        ChooserenderDropDownLabel     matlab.ui.control.Label
        ISET3dRenderParametersLabel   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function refreshApp(app)
            % Fill out text fields in the display based on the main
            % pull down.
            
            prefs = getpref('ISETDocker');
            
            % Each one of these could be edited and a new setpref
            % called when the entries in the window are changed.
            app.DockerimageEditField.Value = prefs.dockerImage;

            app.DeviceIDEditField.Value = prefs.deviceID;
            app.DeviceEditField.Value = prefs.device;

            app.BatchEditField.Value = prefs.batch;

            app.PBRTContainerEditField.Value = prefs.PBRTContainer;
            app.PBRTResourcesEditField.Value = prefs.PBRTResources;
            app.remotePBRTResourcesEditField.Value = prefs.remotePBRTResources;
            
            app.RendercontextEditField.Value = prefs.renderContext;
            app.RemotehostEditField.Value = prefs.remoteHost;

            % Remote?  Or just Work?
            app.RemoteWorkDirEditField.Value = prefs.workDir;
            app.RemoteuserEditField.Value = prefs.remoteUser;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooserenderDropDown
        function chooseRender(app, event)
            % When we choose a pull down, we reader the selection and
            % fill the other fields.
            value = app.ChooserenderDropDown.Value;
            
            %{
            getpref('ISETDocker')
                 device: 'gpu'
               deviceID: 1
            dockerImage: 'digitalprodev/pbrt-v4-gpu-ampere-ti'
                workDir: '/home/wandell/ISETRemoteRender'
          renderContext: 'remote-orange'
             remoteHost: 'orange.stanford.edu'
             remoteUser: 'wandell'
    remotePBRTResources: '/acorn/data/iset/PBRTResources'
                  batch: 0
          PBRTResources: '/acorn/data/iset/PBRTResources'
          PBRTContainer: 'pbrt-gpu-wandell15600'
            %}
            thisD = isetdocker;

            switch value
                case 'orange 3090 (remote)'
                    thisD.preset('remoteorange-0')
                case 'orange 3070 (remote)'
                    thisD.preset('remoteorange-1')
                case 'orange-cpu (remote)'
                    % Used for human eye rendering
                    thisD.preset('remoteorange-cpu')
                case 'mux 3080 (remote)'
                    thisD.preset('remotemux-0')                
                case 'mux 2080 (remote)'
                    thisD.preset('remotemux-1')
                case 'cnidl (remote)'
                    thisD.preset('remotecnidl')
                case 'local gpu'
                    % Needs work.
                    thisD.preset('localgpu')
                case 'local cpu'
                    disp('NYI')
                otherwise
                    error('Unknown choice %s\n',value)
            end
            refreshApp(app);
        end

        % Value changed function: RemoteuserEditField
        function remoteUser(app, event)
            % This might change the remote working directory, too,
            % when we get that smart.
            value = app.RemoteuserEditField.Value;
            setpref('ISETDocker','remoteUser',value);
            refreshApp(app);
        end

        % Menu selected function: FileMenu_Close
        function fileMenuClose(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.FileMenu_Close = uimenu(app.FileMenu);
            app.FileMenu_Close.MenuSelectedFcn = createCallbackFcn(app, @fileMenuClose, true);
            app.FileMenu_Close.Text = 'Quit';
            app.ISET3dRenderParametersLabel = uilabel(app.UIFigure);
            app.ISET3dRenderParametersLabel.Text = 'ISET3d Render Parameters';
            app.ChooserenderDropDownLabel = uilabel(app.UIFigure);
            app.ChooserenderDropDownLabel.Text = 'Choose render';
            app.ChooserenderDropDown = uidropdown(app.UIFigure);
            app.ChooserenderDropDown.Items = {'orange 3090 (remote)', 'orange 3070 (remote)', 'mux 3080 (remote)', 'mux 2080 (remote)', 'cnidl (remote)', 'local gpu', 'local cpu'};
            app.ChooserenderDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseRender, true);
            app.ChooserenderDropDown.Value = 'orange 3090 (remote)';
            app.DockerimageEditFieldLabel = uilabel(app.UIFigure);
            app.DockerimageEditFieldLabel.Text = 'Docker image';
            app.DockerimageEditField = uieditfield(app.UIFigure, 'text');
            app.DockerimageEditField.Value = '''digitalprodev/pbrt-v4-gpu-ampere-ti';
            app.RemoteuserEditFieldLabel = uilabel(app.UIFigure);
            app.RemoteuserEditFieldLabel.Text = 'Remote user';
            app.RemoteuserEditField = uieditfield(app.UIFigure, 'text');
            app.RemoteuserEditField.ValueChangedFcn = createCallbackFcn(app, @remoteUser, true);
            app.RemoteuserEditField.Value = 'wandell';
            app.RemoteWorkDirEditFieldLabel = uilabel(app.UIFigure);
            app.RemoteWorkDirEditFieldLabel.Text = 'Remote Work Dir';
            app.RemoteWorkDirEditField = uieditfield(app.UIFigure, 'text');
            app.RemoteWorkDirEditField.Value = 'home/wandell/ISETRemoteRender';
            app.RemotehostEditFieldLabel = uilabel(app.UIFigure);
            app.RemotehostEditFieldLabel.Text = 'Remote host';
            app.RemotehostEditField = uieditfield(app.UIFigure, 'text');
            app.RemotehostEditField.Value = 'orange.stanford.edu';
            app.RendercontextEditFieldLabel = uilabel(app.UIFigure);
            app.RendercontextEditFieldLabel.Text = 'Render context';
            app.RendercontextEditField = uieditfield(app.UIFigure, 'text');
            app.RendercontextEditField.Value = 'remote-orange';
            app.DeviceEditFieldLabel = uilabel(app.UIFigure);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.DeviceEditField = uieditfield(app.UIFigure, 'text');
            app.DeviceEditField.Value = 'GPU';
            app.remotePBRTResourcesLabel = uilabel(app.UIFigure);
            app.remotePBRTResourcesLabel.Text = 'remote PBRT Resources';
            app.remotePBRTResourcesEditField = uieditfield(app.UIFigure, 'text');
            app.remotePBRTResourcesEditField.Value = '/acorn/data/iset/PBRTResources';
            app.PBRTResourcesLabel = uilabel(app.UIFigure);
            app.PBRTResourcesLabel.Text = 'PBRT Resources';
            app.PBRTResourcesEditField = uieditfield(app.UIFigure, 'text');
            app.PBRTResourcesEditField.Value = '/acorn/data/iset/PBRTResources';
            app.PBRTContainerLabel = uilabel(app.UIFigure);
            app.PBRTContainerLabel.Text = 'PBRT Container';
            app.PBRTContainerEditField = uieditfield(app.UIFigure, 'text');
            app.PBRTContainerEditField.Value = 'pbrt-gpu-wandell15600';
            app.BatchEditField_2Label = uilabel(app.UIFigure);
            app.BatchEditField_2Label.Text = 'Batch';
            app.BatchEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeviceIDEditField_2Label = uilabel(app.UIFigure);
            app.DeviceIDEditField_2Label.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeviceIDEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = remoteDocker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end